classdef myclassifier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UITable                  matlab.ui.control.Table
        UIAxes                   matlab.ui.control.UIAxes
        Lamp                     matlab.ui.control.Lamp
        RunButton                matlab.ui.control.StateButton
        TextArea                 matlab.ui.control.TextArea
        LoadButton               matlab.ui.control.Button
        Switch                   matlab.ui.control.ToggleSwitch
        ListBox                  matlab.ui.control.ListBox
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes_3                 matlab.ui.control.UIAxes
        InputParametersLabel     matlab.ui.control.Label
        FeaturesPanel            matlab.ui.container.Panel
        DropDown_2               matlab.ui.control.DropDown
        DropDown                 matlab.ui.control.DropDown
        DropDown_3               matlab.ui.control.DropDown
        ThresholdingPanel        matlab.ui.container.Panel
        UIAxes_4                 matlab.ui.control.UIAxes
        ExitButton               matlab.ui.control.StateButton
        SegmentationButtonGroup  matlab.ui.container.ButtonGroup
        SupervisedButton         matlab.ui.control.ToggleButton
        UnsupervisedButton       matlab.ui.control.ToggleButton
        Slider                   matlab.ui.control.Slider
        ValueEditFieldLabel      matlab.ui.control.Label
        ValueEditField           matlab.ui.control.NumericEditField
        TextArea_2               matlab.ui.control.TextArea
        OutputParametersLabel    matlab.ui.control.Label
        SelectImageLabel         matlab.ui.control.Label
    end


    properties (Access = public)
        allfiles  % Description
        thresholdValue
        selectedButton % Description

    end

    properties (Access = private)
        button % Description
        filename % Description
        pathname % Description
%         s % Description
%         selectedButton % Description

    end


    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            promptMessage = sprintf('Which dataset do you want to use?');
            app.button = questdlg(promptMessage, 'Dataset', 'Dataset 1', 'Dataset 2',...
                                'Dataset 3', 'Cancel');
            if strcmp(app.button, 'Cancel')
                return;
            end
            
            
            [~, app.pathname]  = uigetfile('*.*', 'Specify an image file'); 
            app.allfiles = dir( fullfile(app.pathname,'*.png') );%# list all *.xyz files
            app.allfiles = {app.allfiles.name}'; %'# file names   
            
            
            img_combined = [];
            Table = [];
            switch app.button
            % Separate dataset 1 into training and testing set
                   case 'Dataset 1'        
                   % reshape cell into 100 by 3 images
                   newshape = reshape(app.allfiles,[100,3]);
                   % Assign 15% of the data as testing set
                   testset = newshape(1:15, :);
                   % Assign 85% of data as testing set    
                   trainset = newshape(16:end,:);
        
            % Separate dataset 2 into training and testing set
                   case 'Dataset 2'
                   % reshape cell into 100 by 16 images
                   newshape = reshape(app.allfiles,[16,100]);
                   % Assign 15% of the data as testing set
                   testset = newshape(:,1:15);
                   % Assign 85% of data as testing set    
                   trainset = newshape(:,16:end);
            % Separate dataset 3 into training and testing set
                   case 'Dataset 3'
                   % reshape cell into 59 by 16 images
                   newshape = reshape(app.allfiles,[16,59]);
                   % Assign 15% of the data as testing set
                   testset = newshape(:,1:9);
                   % Assign 85% of data as testing set    
                   trainset = newshape(:,10:end);
             end 
            % 
            %                     image1 = imread(app.allfiles);
            %                     imshow(image1, 'Parent', app.UIAxes);
            %                     app.TextArea.Value = string(trainset);
            
            app.RunButton.Enable = 'on';
            app.ListBox.Enable = 'on';
            %             app.Slider.Enable = 'on';
            app.ListBox.Items =  app.allfiles;
            app.UnsupervisedButton.Enable = 'on';
            app.SupervisedButton.Enable = 'on';
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
          
            if  isequal(value,'Train')
                app.Lamp.Color = 'yellow';
                app.ListBox.Enable = 'off';
                app.Lamp.Enable = 'on';
                app.Slider.Enable = 'off';
            elseif isequal(value,'Test')
                app.Lamp.Enable = 'off';
                app.ListBox.Enable = 'on';
                app.Lamp.Color = 'green';
                app.Slider.Enable = 'on';
            %                 app.Slider.Value = 0.5;
                app.thresholdValue = 0.5;
            end
            %             app.LoadButton.Enable = 'on';
           
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            global image1 image2 image3 labeledImage coloredLabels T
            
            app.ListBox.Enable = 'on';
            app.ListBox.Items =  app.allfiles;
            %% Testing phase
            % Define value as what user selects from the listbox
            value = app.ListBox.Value;
            
            % Display raw image in top left figure
            image1 = imread(strcat(app.pathname,value));            
            imshow(image1, 'Parent', app.UIAxes);
            
            % Normalize the image selected by user
            image2 = normal3(image1, app.button);
            image3 = enhance(image2);
            
            % Display Normalized image in the top right figure
            %             imshow(image3.img_histeq, 'Parent', app.UIAxes_2);
            %             [BW,~] = createMask(image3.img_histeq, channel1Min, channel1Max,...
            %                                                   channel2Min, channel2Max,...
            %                                                   channel3Min, channel3Max);
            % Display segmented image in the lower left figure
            %             global nucleus 
            %             supervisedvalue = app.SupervisedButton.Value;
            %             unsupervisedvalue = app.SupervisedButton.Value;
             app.selectedButton = app.SegmentationButtonGroup.SelectedObject.Text;

             if     isequal(app.selectedButton,'Unsupervised')
                    [BW,~] = createMask(image3.img_histeq);
                    imshow(BW, 'Parent', app.UIAxes_2);
                    histogram(app.UIAxes_4,'Parent',image3.img_histeq);  
            %                     histogram(image3.img_histeq(:,:,2), 'Parent', app.UIAxes_5);
            %                     histogram(image3.img_histeq(:,:,3), 'Parent', app.UIAxes_6);  
            %              elseif isequal(app.selectedButton,'Supervised')
                    % Run Function makingRef.m function on the dataset to obtain Table on a
                    % dataset
            %                     Table = Makingref(image3.img_histeq,app.ListBox.Value);
                    % Run knnalgorithm.m function using Table as input and generating
                    % trainedclassifier
            %                     [trainedClassifier, ~] = knnalgorithm(Table);
                    % Perform Image Segmentation using trained classifier and image as input in
                    % function SupervisedSegmentation
            %                     [nucleus, ~, ~] = SupervisedSegmentation(image3.img_histeq, trainedClassifier);
            %                     app.thresholdValue = app.Slider.Value;
            %                     [labeledImage, coloredLabels, T] = nucleidetector(image3.img_histeq, app.thresholdValue);                   
            %                     imshow(labeledImage, 'Parent', app.UIAxes_2);
            %                     imshow(coloredLabels, 'Parent', app.UIAxes_3);% 
            %                     originalImage = rgb2gray(image3.img_histeq);
            %                     histogram(originalImage,'Parent', app.UIAxes_4);
            %                     [grayLevels] = histogram(app.UIAxes_4, 'Parent',originalImage);
            
            %                     xlim(app.UIAxes_4,'Parent',[0 grayLevels(end)]); % Scale x axis manually.
            %                     grid on;
            %                     hold on;
            %                     maxYValue = ylim;
            %                     line(app.UIAxes_4,'Parent',[app.thresholdValue, app.thresholdValue], maxYValue, 'Color', 'r');
                    % Place a text label on the bar chart showing the threshold.
            %                     annotationText = sprintf('Thresholded at %d gray levels', app.thresholdValue);
            %                     % For text(), the x and y need to be of the data class "double" so let's cast both to double.
            %                     text(app.UIAxes_4,'Parent', double(app.thresholdValue + 5), double(0.5 * maxYValue(2)), annotationText, 'FontSize', 10, 'Color', [0 .5 0]);
            %                     text(app.UIAxes_4,'Parent', double(app.thresholdValue - 70), double(0.94 * maxYValue(2)), 'Background', 'FontSize', 10, 'Color', [0 0 .5]);
            %                     text(app.UIAxes_4,'Parent', double(app.thresholdValue + 50), double(0.94 * maxYValue(2)), 'Foreground', 'FontSize', 10, 'Color', [0 0 .5]);
            %                     app.UITable.Data = T;
             elseif isequal(app.selectedButton,'Supervised')
                    app.thresholdValue  = app.Slider.Value;          
                    [labeledImage, coloredLabels, T] = nucleidetector(image3.img_histeq, app.thresholdValue);                   
                    imshow(labeledImage, 'Parent', app.UIAxes_2);
                    imshow(coloredLabels, 'Parent', app.UIAxes_3);% 
                    originalImage = rgb2gray(image3.img_histeq);
                    histogram(originalImage,'Parent', app.UIAxes_4);
                    app.UITable.Data = T;
            end             
     
            
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.ListBox.Value;
            app.TextArea.Value = value;
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.TextArea.Value = value;
                         app.RunButton.Enable = 'on';            
        end

        % Value changed function: ExitButton
        function ExitButtonValueChanged(app, event)
            %             value = app.ExitButton.Value;
             close all force
        end

        % Selection changed function: SegmentationButtonGroup
        function SegmentationButtonGroupSelectionChanged(app, event)
            app.selectedButton = app.SegmentationButtonGroup.SelectedObject.Text;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            global image3 labeledImage coloredLabels T
            app.thresholdValue  = app.Slider.Value;   
            [labeledImage, coloredLabels, T] = nucleidetector(image3.img_histeq, app.thresholdValue);                   
            imshow(labeledImage, 'Parent', app.UIAxes_2);
            imshow(coloredLabels, 'Parent', app.UIAxes_3);% 
            originalImage = rgb2gray(image3.img_histeq);
            histogram(originalImage,'Parent', app.UIAxes_4);
            app.UITable.Data = T;
            app.ValueEditField.Value = app.thresholdValue;
            
        end

        % Value changed function: ValueEditField
        function ValueEditFieldValueChanged(app, event)
           app.ValueEditField.Value = app.Slider.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Raw Image');
            app.Lamp = uilamp(app.UIFigure);
            app.RunButton = uibutton(app.UIFigure, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.Items = {'Test', 'Train'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Test';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Enable = 'off';
            app.ListBox.Value = {};
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Segmented Image_ Gray Scale Image');
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Pseudo Colored Labels');
            app.InputParametersLabel = uilabel(app.UIFigure);
            app.InputParametersLabel.Text = 'Input Parameters';
            app.FeaturesPanel = uipanel(app.UIFigure);
            app.FeaturesPanel.Title = 'Features';
            app.DropDown_2 = uidropdown(app.FeaturesPanel);
            app.DropDown_2.Enable = 'off';
            app.DropDown = uidropdown(app.FeaturesPanel);
            app.DropDown.Enable = 'off';
            app.DropDown_3 = uidropdown(app.FeaturesPanel);
            app.DropDown_3.Enable = 'off';
            app.ThresholdingPanel = uipanel(app.UIFigure);
            app.ThresholdingPanel.Title = 'Thresholding';
            app.UIAxes_4 = uiaxes(app.ThresholdingPanel);
            app.UIAxes_4.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ExitButton = uibutton(app.UIFigure, 'state');
            app.ExitButton.ValueChangedFcn = createCallbackFcn(app, @ExitButtonValueChanged, true);
            app.ExitButton.Text = 'Exit';
            app.SegmentationButtonGroup = uibuttongroup(app.UIFigure);
            app.SegmentationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SegmentationButtonGroupSelectionChanged, true);
            app.SegmentationButtonGroup.Title = 'Segmentation';
            app.SupervisedButton = uitogglebutton(app.SegmentationButtonGroup);
            app.SupervisedButton.Enable = 'off';
            app.SupervisedButton.Text = 'Supervised';
            app.SupervisedButton.Value = true;
            app.UnsupervisedButton = uitogglebutton(app.SegmentationButtonGroup);
            app.UnsupervisedButton.Enable = 'off';
            app.UnsupervisedButton.Text = 'Unsupervised';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 0.5;
            app.ValueEditFieldLabel = uilabel(app.UIFigure);
            app.ValueEditFieldLabel.Text = 'Value';
            app.ValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValueEditField.ValueChangedFcn = createCallbackFcn(app, @ValueEditFieldValueChanged, true);
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.OutputParametersLabel = uilabel(app.UIFigure);
            app.OutputParametersLabel.Text = 'Output Parameters';
            app.SelectImageLabel = uilabel(app.UIFigure);
            app.SelectImageLabel.Text = 'Select Image';
        end
    end

    methods (Access = public)

        % Construct app
        function app = myclassifier()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end